classdef UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ForceCombinationCheckBox  matlab.ui.control.CheckBox
        RunButton                 matlab.ui.control.Button
        ForceCheckBox             matlab.ui.control.CheckBox
        CheckBox                  matlab.ui.control.CheckBox
        CheckBox_2                matlab.ui.control.CheckBox
        CheckBox_3                matlab.ui.control.CheckBox
        CheckBox_4                matlab.ui.control.CheckBox
        CheckBox_5                matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
     
        ForceFlage=app.ForceCheckBox.Value;
        ForceComFlage=app.ForceCombinationCheckBox.Value;
        [r,ForceplateNum]=find([app.CheckBox.Value app.CheckBox_2.Value app.CheckBox_3.Value app.CheckBox_4.Value app.CheckBox_5.Value]==1);
%         ForceplateNum=app.ForcePlatenumberEditField_2.Value;
        [fname,folder] = uigetfile('*.c3d');
        Process_Data(fname,folder,ForceFlage,ForceComFlage,ForceplateNum)
        end

        % Value changed function: ForceCheckBox
        function ForceCheckBoxValueChanged(app, event)
            value = app.ForceCheckBox.Value;
            app.ForceCombinationCheckBox.Enable=value;
            app.ForcePlatenumberEditField.Enable=value;
        end

        % Callback function
        function Loadc3dButtonPushed(app, event)
        [fname,folder] = uigetfile('*.c3d');
        app.Label.Text=[folder fname];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceCombinationCheckBox = uicheckbox(app.UIFigure);
            app.ForceCombinationCheckBox.Text = 'ForceCombination';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ForceCheckBox = uicheckbox(app.UIFigure);
            app.ForceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ForceCheckBoxValueChanged, true);
            app.ForceCheckBox.Text = 'Force';
            app.ForceCheckBox.Value = true;
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '1';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '2';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '3';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '4';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end